classdef exe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabCOVER                        matlab.ui.container.Panel
        TestmodeLINK                    matlab.ui.control.Button
        PlottingLINK                    matlab.ui.control.Button
        infoLINK                        matlab.ui.control.Button
        InputparametersLINK             matlab.ui.control.Button
        DashboardLINK                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputparametersTab              matlab.ui.container.Tab
        ElevationBeamwidthdegEditField  matlab.ui.control.NumericEditField
        ElevationBeamwidthdegEditFieldLabel  matlab.ui.control.Label
        AzimuthBeamwidthdegEditField    matlab.ui.control.NumericEditField
        AzimuthBeamwidthdegEditFieldLabel  matlab.ui.control.Label
        FixedanglesSwitch               matlab.ui.control.Switch
        FixedanglesSwitchLabel          matlab.ui.control.Label
        BeamformingSwitch               matlab.ui.control.Switch
        DisableBeamformingLabel         matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        TxGainantennaelementdBiEditField  matlab.ui.control.NumericEditField
        TxGainantennaelementdBiEditField_2Label  matlab.ui.control.Label
        MediumImpedenceOhmEditField     matlab.ui.control.NumericEditField
        MediumImpedenceOhmEditFieldLabel  matlab.ui.control.Label
        Minimum3dBangledegEditField     matlab.ui.control.NumericEditField
        Minimum3dBangledegEditFieldLabel  matlab.ui.control.Label
        ReceivernoisefiguredBEditField  matlab.ui.control.NumericEditField
        ReceivernoisefiguredBEditFieldLabel  matlab.ui.control.Label
        BandwidthMHzEditField           matlab.ui.control.NumericEditField
        BandwidthMHzEditFieldLabel      matlab.ui.control.Label
        IntrasectorinterferenceSwitch   matlab.ui.control.Switch
        IntrasectorinterferenceSwitchLabel  matlab.ui.control.Label
        ArraytotaltransmitpowerdBmEditField  matlab.ui.control.NumericEditField
        ArraytotaltransmitpowerdBmEditFieldLabel  matlab.ui.control.Label
        ArraysizecolumnEditField        matlab.ui.control.NumericEditField
        ArraysizecolumnEditFieldLabel   matlab.ui.control.Label
        ArraysizerowEditField           matlab.ui.control.NumericEditField
        ArraysizerowEditFieldLabel      matlab.ui.control.Label
        SidelobelevellimitSLAvdBEditField  matlab.ui.control.NumericEditField
        SidelobelevellimitSLAvdBEditFieldLabel  matlab.ui.control.Label
        FronttoBackRatioAmdBEditField   matlab.ui.control.NumericEditField
        FronttoBackRatioAmdBEditFieldLabel  matlab.ui.control.Label
        PropagationconditionDropDown    matlab.ui.control.DropDown
        PropagationconditionDropDownLabel  matlab.ui.control.Label
        CarrierfrequencyGHzEditField    matlab.ui.control.NumericEditField
        CarrierfrequencyGHzEditFieldLabel  matlab.ui.control.Label
        gNBheigthmEditField             matlab.ui.control.NumericEditField
        gNBheigthmEditFieldLabel        matlab.ui.control.Label
        UEheightmEditField              matlab.ui.control.NumericEditField
        UEheightmEditFieldLabel         matlab.ui.control.Label
        LocalizationAccuracymEditField  matlab.ui.control.NumericEditField
        LocalizationAccuracymEditFieldLabel  matlab.ui.control.Label
        SpacingmeasurespotgridEditField  matlab.ui.control.NumericEditField
        SpacingmeasurespotgridEditFieldLabel  matlab.ui.control.Label
        PARAMETERSLabel                 matlab.ui.control.Label
        TestmodeTab                     matlab.ui.container.Tab
        PostprocessingCheckBox          matlab.ui.control.CheckBox
        DisablecalibrationCheckBox      matlab.ui.control.CheckBox
        paneltopologyplot               matlab.ui.container.Panel
        BasemapstyleButtonGroup         matlab.ui.container.ButtonGroup
        topographicButton               matlab.ui.control.ToggleButton
        streetslightButton              matlab.ui.control.ToggleButton
        streetsdarkButton               matlab.ui.control.ToggleButton
        satelliteButton                 matlab.ui.control.ToggleButton
        streetsButton                   matlab.ui.control.ToggleButton
        Lamp                            matlab.ui.control.Lamp
        TopologyButtonGroup             matlab.ui.container.ButtonGroup
        RealButton                      matlab.ui.control.RadioButton
        SyntheticButton                 matlab.ui.control.RadioButton
        KMLfileDropDown                 matlab.ui.control.DropDown
        KMLfileDropDownLabel            matlab.ui.control.Label
        SinglemodeparametersPanel       matlab.ui.container.Panel
        cEditField                      matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        NumberofgNBsEditField           matlab.ui.control.NumericEditField
        NumberofgNBsEditFieldLabel      matlab.ui.control.Label
        MultimodeparametersPanel        matlab.ui.container.Panel
        cEditField_2                    matlab.ui.control.NumericEditField
        cEditField_2Label               matlab.ui.control.Label
        bEditField_2                    matlab.ui.control.NumericEditField
        bEditField_2Label               matlab.ui.control.Label
        aEditField_2                    matlab.ui.control.NumericEditField
        aEditField_2Label               matlab.ui.control.Label
        MOBJweightsexplorationCheckBox  matlab.ui.control.CheckBox
        GroupattemptsEditField          matlab.ui.control.NumericEditField
        GroupattemptsEditFieldLabel     matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        MaximumMOBJFcoefficientEditField  matlab.ui.control.NumericEditField
        MaximumMOBJFcoefficientEditFieldLabel  matlab.ui.control.Label
        MinimumMOBJFcoefficientEditField  matlab.ui.control.NumericEditField
        MinimumMOBJFcoefficientEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofgNBsEditField    matlab.ui.control.NumericEditField
        MaximumnumberofgNBsEditField_2Label  matlab.ui.control.Label
        MinimumnumberofgNBsEditField    matlab.ui.control.NumericEditField
        MinimumnumberofgNBsEditFieldLabel  matlab.ui.control.Label
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        MultimodeButton                 matlab.ui.control.RadioButton
        SinglemodeButton                matlab.ui.control.RadioButton
        GeographictopologyunavailablefortheSyntheticcaseLabel  matlab.ui.control.Label
        PlottingTab                     matlab.ui.container.Tab
        IDEditField                     matlab.ui.control.NumericEditField
        IDEditFieldLabel                matlab.ui.control.Label
        IMPORTButton                    matlab.ui.control.Button
        CLEARButton_3                   matlab.ui.control.Button
        CLEARButton_2                   matlab.ui.control.Button
        winnerplotButton                matlab.ui.control.Button
        CurrentbestMOBJtrendButton      matlab.ui.control.Button
        SensitivityanalysisButton       matlab.ui.control.Button
        CLEARButton                     matlab.ui.control.Button
        ShowoutsideGUICheckBox          matlab.ui.control.CheckBox
        BeamsHeatmapButton              matlab.ui.control.Button
        ServedandUnservedspotsButton    matlab.ui.control.Button
        MeasurespotgridButton           matlab.ui.control.Button
        ECDFThroughputButton            matlab.ui.control.Button
        ECDFEMFstrengthButton           matlab.ui.control.Button
        throughputecdf                  matlab.ui.control.UIAxes
        emfecdf                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        DashboardTab                    matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        SubsetAmplitudeDropDown         matlab.ui.control.DropDown
        SubsetAmplitudeDropDownLabel    matlab.ui.control.Label
        ParetofrontsolutionsButton      matlab.ui.control.Button
        ReplotButton                    matlab.ui.control.Button
        Panel_14                        matlab.ui.container.Panel
        WinnerID                        matlab.ui.control.TextArea
        TextArea_2Label_9               matlab.ui.control.Label
        WinnerIDLabel                   matlab.ui.control.Label
        Panel_13                        matlab.ui.container.Panel
        liveplot_5                      matlab.ui.control.UIAxes
        Panel_12                        matlab.ui.container.Panel
        liveplot_4                      matlab.ui.control.UIAxes
        Panel_11                        matlab.ui.container.Panel
        liveplot_3                      matlab.ui.control.UIAxes
        Panel_10                        matlab.ui.container.Panel
        testmodeviewer                  matlab.ui.control.TextArea
        TextArea_2Label_8               matlab.ui.control.Label
        testmodeviewerLabel             matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        Executiontotaltime              matlab.ui.control.TextArea
        TextArea_2Label_7               matlab.ui.control.Label
        ExecutiontotaltimeLabel         matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        calibrationtime                 matlab.ui.control.TextArea
        TextArea_2Label_6               matlab.ui.control.Label
        calibrationtimerLabel           matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        CalibrationstatusLabel          matlab.ui.control.Label
        CalibrationstatusLamp           matlab.ui.control.Lamp
        Panel_6                         matlab.ui.container.Panel
        liveid                          matlab.ui.control.TextArea
        TextArea_2Label_3               matlab.ui.control.Label
        CurrentIDLabel                  matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        liveplot_2                      matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        liveplot                        matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        SubsetAmplitudeGauge            matlab.ui.control.SemicircularGauge
        SubsetamplitudeLabel            matlab.ui.control.Label
        livenumbersubsetamplitude       matlab.ui.control.TextArea
        TextArea_2Label_5               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        AttemptsGauge                   matlab.ui.control.SemicircularGauge
        AttemptsGaugeLabel              matlab.ui.control.Label
        livenumberattempts              matlab.ui.control.TextArea
        TextArea_2Label_4               matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        FORCESTOPButton                 matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = func(app)
              if app.ModeButtonGroup.Buttons(1).Value == 1
                app.SinglemodeparametersPanel.Visible = 'on'
                app.MultimodeparametersPanel.Visible = 'off'
              end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath Data functions toload Icons
            addpath(genpath('cache'))
            addpath(genpath('Scripts'))
            if exist('TESTS', 'dir')
                rmdir TESTS s;
                mkdir TESTS;
            else
                mkdir TESTS;
            end
            p = genpath('TESTS');
            addpath(p);

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CalibrationstatusLamp.Color = [1 0 0];
            app.calibrationtime.Value = '';
            app.Executiontotaltime.Value= '';
            drawnow;
            if app.ModeButtonGroup.Buttons(1).Value == 1
                app.testmodeviewer.Value = "SINGLE";
                drawnow;
            else
                app.testmodeviewer.Value = "MULTI";
                drawnow;
            end
            run("TORUN.m")

        end

        % Button pushed function: ECDFEMFstrengthButton
        function ECDFEMFstrengthButtonPushed(app, event)
            
            s = 'Scripts\plotting\ecdf_plot_emf_strength.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Button pushed function: ECDFThroughputButton
        function ECDFThroughputButtonPushed(app, event)
             
             s = 'Scripts\plotting\ecdf_plot_throughput.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Button pushed function: MeasurespotgridButton
        function MeasurespotgridButtonPushed(app, event)
            
            s = 'Scripts\plotting\plot_measure_spot_grid.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Button pushed function: ServedandUnservedspotsButton
        function ServedandUnservedspotsButtonPushed(app, event)

            s = 'Scripts\plotting\plot_served_unserved.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Button pushed function: BeamsHeatmapButton
        function BeamsHeatmapButtonPushed(app, event)

            s = 'Scripts\plotting\plot_beam_heatmap.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
            
        end

        % Button down function: ModeButtonGroup
        function ModeButtonGroupButtonDown(app, event)
%           
%             if app.ModeButtonGroup.Buttons(1).Value == 1
%                 app.MinimumnumberofgNBsEditField.Enable = "off";
%                 app.MaximumnumberofgNBsEditField.Enable = "off";
%                 app.StepEditField.Enable = "off";
%                 app.MaximumMOBJFcoefficientEditField.Enable = "off";
%                 app.MinimumMOBJFcoefficientEditField.Enable = "off";
%                 app.GroupattemptsEditField.Enable = "off";
% 
%             end
%       
%             if app.ModeButtonGroup.Buttons(2).Value == 1 
%                 app.MinimumnumberofgNBsEditField.Enable = "on"
%                 app.MaximumnumberofgNBsEditField.Enable = "on"
%                 app.StepEditField.Enable = "on"
%                 app.MaximumMOBJFcoefficientEditField.Enable = "on"
%                 app.MinimumMOBJFcoefficientEditField.Enable = "on"
%                 app.GroupattemptsEditField.Enable = "on"
%             end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes)
            app.UIAxes.Title.String = 'Title'
            app.UIAxes.XLabel.String = 'X label'
            app.UIAxes.YLabel.String = 'Y label'
            axis(app.UIAxes, 'tight')
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            
            if app.ModeButtonGroup.Buttons(1).Value == 1
                app.MinimumnumberofgNBsEditFieldLabel.Enable = "off";
                app.MinimumnumberofgNBsEditField.Enable = "off";
                app.MaximumnumberofgNBsEditField_2Label.Enable = "off";
                app.MaximumnumberofgNBsEditField.Enable = "off";
                app.StepEditFieldLabel.Enable = "off";
                app.StepEditField.Enable = "off";
                app.MOBJweightsexplorationCheckBox.Enable = "off";
                app.MaximumMOBJFcoefficientEditFieldLabel.Enable = "off";
                app.MaximumMOBJFcoefficientEditField.Enable = "off";
                app.MinimumMOBJFcoefficientEditFieldLabel.Enable = "off";
                app.MinimumMOBJFcoefficientEditField.Enable = "off";
                app.GroupattemptsEditFieldLabel.Enable = "off";
                app.GroupattemptsEditField.Enable = "off";
                app.aEditField_2Label.Enable = "off";
                app.aEditField_2.Enable = "off";
                app.bEditField_2Label.Enable = "off";
                app.bEditField_2.Enable = "off";
                app.cEditField_2Label.Enable = "off";
                app.cEditField_2.Enable = "off";
                %text edit fields da mostrare nel tab test mode se viene
                %selezionato multimode
                app.NumberofgNBsEditFieldLabel.Enable = "on";
                app.NumberofgNBsEditField.Enable = "on";
                app.aEditFieldLabel.Enable ="on"
                app.aEditField.Enable = "on"
                app.bEditFieldLabel.Enable ="on"
                app.bEditField.Enable = "on"
                app.cEditFieldLabel.Enable ="on"
                app.cEditField.Enable = "on"
                app.SinglemodeparametersPanel.BackgroundColor = [0.78 0.78 0.78 ];
                app.MultimodeparametersPanel.BackgroundColor = [1 1 1];

            end
             if app.ModeButtonGroup.Buttons(2).Value == 1 %when multimode is selected
                 %Elements in the multimode panel
                app.MinimumnumberofgNBsEditFieldLabel.Enable = "on";
                app.MinimumnumberofgNBsEditField.Enable = "on";
                app.MaximumnumberofgNBsEditField_2Label.Enable = "on";
                app.MaximumnumberofgNBsEditField.Enable = "on";
                %Coefficent tuning part
                app.StepEditFieldLabel.Enable = "off";
                app.StepEditField.Enable = "off";
                app.MaximumMOBJFcoefficientEditFieldLabel.Enable = "off";
                app.MaximumMOBJFcoefficientEditField.Enable = "off";
                app.MinimumMOBJFcoefficientEditFieldLabel.Enable = "off";
                app.MinimumMOBJFcoefficientEditField.Enable = "off";
                app.MOBJweightsexplorationCheckBox.Enable = "on";

                app.GroupattemptsEditFieldLabel.Enable = "on";
                app.GroupattemptsEditField.Enable = "on";
                app.aEditField_2Label.Enable = "on";
                app.aEditField_2.Enable = "on";
                app.bEditField_2Label.Enable = "on";
                app.bEditField_2.Enable = "on";
                app.cEditField_2Label.Enable = "on";
                app.cEditField_2.Enable = "on";

                %Elements in the single mode panel 
                app.NumberofgNBsEditFieldLabel.Enable = "off";
                app.NumberofgNBsEditField.Enable = "off";
                app.aEditFieldLabel.Enable ="off"
                app.aEditField.Enable = "off"
                app.bEditFieldLabel.Enable ="off"
                app.bEditField.Enable = "off"
                app.cEditFieldLabel.Enable ="off"
                app.cEditField.Enable = "off"
                
        %
                app.SinglemodeparametersPanel.BackgroundColor = [1 1 1]; %single mode becomes white
                app.MultimodeparametersPanel.BackgroundColor = [0.78 0.78 0.78 ];% multi mode becomes gray
            end
        end

        % Button down function: TestmodeTab
        function TestmodeTabButtonDown(app, event)
            
            file_list = dir(fullfile('Scripts/kml','*.kml'));
            file_list={file_list.name};
            app.KMLfileDropDown.Items = file_list;
             if app.ModeButtonGroup.Buttons(1).Value == 1
%                 app.SinglemodeparametersPanel.Visible = 'on'
%                 app.MultimodeparametersPanel.Visible = 'off'
            end
        end

        % Button down function: SinglemodeparametersPanel
        function SinglemodeparametersPanelButtonDown(app, event)
            
        end

        % Button pushed function: SensitivityanalysisButton
        function SensitivityanalysisButtonPushed(app, event)

             s = 'Scripts\plotting\plot_mobj_trend_weights.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
            
        end

        % Button pushed function: CurrentbestMOBJtrendButton
        function CurrentbestMOBJtrendButtonPushed(app, event)

            s = 'Scripts\plotting\plot_mobj_curr_best_weights.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Button pushed function: winnerplotButton
        function winnerplotButtonPushed(app, event)

            s = 'Scripts\plotting\winnerplot.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Clicked callback: KMLfileDropDown
        function KMLfileDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
           

        end

        % Selection changed function: TopologyButtonGroup
        function TopologyButtonGroupSelectionChanged(app, event)
            selectedButton = app.TopologyButtonGroup.SelectedObject;
            if app.TopologyButtonGroup.Buttons(1).Value == 1
                app.KMLfileDropDown.Enable = "off";
                app.KMLfileDropDownLabel.Enable ="off";
                app.Lamp.Color = [1 0 0];
                app.BasemapstyleButtonGroup.Enable ="off";
                app.paneltopologyplot.Visible = "off";
                app.paneltopologyplot.Enable = "off";
                app.GeographictopologyunavailablefortheSyntheticcaseLabel.Visible = "on";
            end
            if app.TopologyButtonGroup.Buttons(2).Value == 1
                app.KMLfileDropDown.Enable = "on";
                app.KMLfileDropDownLabel.Enable="on";
                app.Lamp.Color = [0 1 0];
                app.BasemapstyleButtonGroup.Enable ="on";
                app.paneltopologyplot.Visible = "on";
                app.paneltopologyplot.Enable = "on";
                app.GeographictopologyunavailablefortheSyntheticcaseLabel.Visible = "off";
                run("topologypreviewer.m")
            end
        end

        % Value changed function: MOBJweightsexplorationCheckBox
        function MOBJweightsexplorationCheckBoxValueChanged(app, event)
            value = app.MOBJweightsexplorationCheckBox.Value;
            if value == 1
                 %disabilita a b c 
                app.aEditField_2Label.Enable = "off";
                app.aEditField_2.Enable = "off";
                app.bEditField_2Label.Enable = "off";
                app.bEditField_2.Enable = "off";
                app.cEditField_2Label.Enable = "off";
                app.cEditField_2.Enable = "off";

                %abilita min max e step
                app.MaximumMOBJFcoefficientEditFieldLabel.Enable = "on";
                app.MaximumMOBJFcoefficientEditField.Enable = "on";
                app.MinimumMOBJFcoefficientEditFieldLabel.Enable = "on";
                app.MinimumMOBJFcoefficientEditField.Enable = "on";
                app.StepEditFieldLabel.Enable = "on";
                app.StepEditField.Enable = "on";
            
            else
                %abilita a b c 
                app.aEditField_2Label.Enable = "on";
                app.aEditField_2.Enable = "on";
                app.bEditField_2Label.Enable = "on";
                app.bEditField_2.Enable = "on";
                app.cEditField_2Label.Enable = "on";
                app.cEditField_2.Enable = "on";

                %disabilita min max e step
                app.MaximumMOBJFcoefficientEditFieldLabel.Enable = "off";
                app.MaximumMOBJFcoefficientEditField.Enable = "off";
                app.MinimumMOBJFcoefficientEditFieldLabel.Enable = "off";
                app.MinimumMOBJFcoefficientEditField.Enable = "off";
                app.StepEditFieldLabel.Enable = "off";
                app.StepEditField.Enable = "off";
            end
        end

        % Size changed function: DashboardTab
        function DashboardTabSizeChanged(app, event)
            position = app.DashboardTab.Position;
            
        end

        % Value changed function: KMLfileDropDown
        function KMLfileDropDownValueChanged(app, event)
            value = app.KMLfileDropDown.Value;
            run("topologypreviewer.m")
         
        end

        % Button pushed function: FORCESTOPButton
        function FORCESTOPButtonPushed(app, event)
            com.mathworks.mde.cmdwin.CmdWinMLIF.getInstance().processKeyFromC(2,67,'C')
        end

        % Button pushed function: DashboardLINK
        function DashboardLINKPushed(app, event)
            app.TabGroup.SelectedTab = app.DashboardTab;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: InputparametersLINK
        function InputparametersLINKButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputparametersTab;
        end

        % Button pushed function: infoLINK
        function infoLINKButtonPushed(app, event)
            web('https://www.linkedin.com/in/matteo-arciuli-0733b4136/', '-browser')
        end

        % Button pushed function: TestmodeLINK
        function TestmodeLINKButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TestmodeTab;
            file_list = dir(fullfile('Scripts/kml','*.kml'));
            file_list={file_list.name};
            app.KMLfileDropDown.Items = file_list;
            
        end

        % Button pushed function: PlottingLINK
        function PlottingLINKButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PlottingTab;
        end

        % Drop down opening function: KMLfileDropDown
        function KMLfileDropDownOpening(app, event)
          
        end

        % Button down function: BasemapstyleButtonGroup
        function BasemapstyleButtonGroupButtonDown(app, event)
           
        end

        % Selection changed function: BasemapstyleButtonGroup
        function BasemapstyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.BasemapstyleButtonGroup.SelectedObject;
             app.paneltopologyplot.Children.Basemap = app.BasemapstyleButtonGroup.SelectedObject.Text;
             if app.BasemapstyleButtonGroup.SelectedObject.Text == "satellite"
                 app.paneltopologyplot.Children.Children.MarkerFaceColor =  [1 0 0];
             else
                 app.paneltopologyplot.Children.Children.MarkerFaceColor =  [0 0 0];
             end
        end

        % Value changed function: liveid
        function liveidValueChanged(app, event)
            value = app.liveid.Value;
            
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            cla(app.emfecdf)
            app.emfecdf.Title.String = 'Title'
            app.emfecdf.XLabel.String = 'X label'
            app.emfecdf.YLabel.String = 'Y label'
            axis(app.emfecdf, 'tight')
        end

        % Button pushed function: CLEARButton_3
        function CLEARButton_3Pushed(app, event)
            cla(app.throughputecdf)
            app.throughputecdf.Title.String = 'Title'
            app.throughputecdf.XLabel.String = 'X label'
            app.throughputecdf.YLabel.String = 'Y label'
            axis(app.throughputecdf, 'tight')
        end

        % Value changed function: BeamformingSwitch
        function BeamformingSwitchValueChanged(app, event)
            value = app.BeamformingSwitch.Value;
            if value == 1
                app.FixedanglesSwitch.Enable = "on";
            else
                app.FixedanglesSwitch.Enable = "off";
            end
        end

        % Value changed function: FixedanglesSwitch
        function FixedanglesSwitchValueChanged(app, event)
            value = app.FixedanglesSwitch.Value;
            if value == 1 
                app.ElevationBeamwidthdegEditField.Enable = "on";
                app.ElevationBeamwidthdegEditFieldLabel.Enable = 'on';
                app.AzimuthBeamwidthdegEditField.Enable = "on";
                app.AzimuthBeamwidthdegEditFieldLabel.Enable = "on";
                app.Minimum3dBangledegEditField.Enable = "off";
                app.Minimum3dBangledegEditFieldLabel.Enable = "off";

            else
                app.ElevationBeamwidthdegEditField.Enable = "off";
                app.ElevationBeamwidthdegEditFieldLabel.Enable = 'off';
                app.AzimuthBeamwidthdegEditField.Enable = "off";
                app.AzimuthBeamwidthdegEditFieldLabel.Enable = "off";
                app.Minimum3dBangledegEditField.Enable = "on";
                app.Minimum3dBangledegEditFieldLabel.Enable = "on";
            end
        end

        % Button pushed function: IMPORTButton
        function IMPORTButtonPushed(app, event)
            if exist('TESTS', 'dir')
                rmdir TESTS s;
                mkdir TESTS;
            else
                mkdir TESTS;
            end
            p = genpath('TESTS');
            addpath(p);
            
            [~,path] = uigetfile('*.',...
   'Select One or More Files', ...
   'MultiSelect', 'on');
         
            path = strcat(path,'*');
            copyfile(path,'TESTS\imported','f');
            addpath("TESTS\imported\")
        end

        % Button down function: liveplot
        function liveplotButtonDown(app, event)
            
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
               
             s = 'Scripts\plotting\replot_dashboard.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Drop down opening function: SubsetAmplitudeDropDown
        function SubsetAmplitudeDropDownOpening(app, event)
          % Specifica il percorso del file
        file = 'cache/Normalization/curr_max_gNB_num.mat';
        
        % Carica il file
        load(file);
        cellArray = arrayfun(@num2str, 1:curr_max_gNB_num, 'UniformOutput', false);
       
        app.SubsetAmplitudeDropDown.Items = cellArray
         
        end

        % Clicked callback: SubsetAmplitudeDropDown
        function SubsetAmplitudeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Button pushed function: ParetofrontsolutionsButton
        function ParetofrontsolutionsButtonPushed(app, event)
              s = 'Scripts\plotting\pareto_solutions_tabular.m';
            if ismac
                s(strfind(s,'\')) = '/';
                % Code to run on Mac platform
            elseif isunix
                s(strfind(s,'\')) = '/';
                % Code to run on Linux platform
            elseif ispc
                % Code to run on Windows platform
            else
                disp('Platform not supported')
            end
            run(s)
        end

        % Callback function
        function SavefigButtonPushed(app, event)
             folder_name = uigetdir;
    if folder_name ~= 0
        saveas(app.UIAxes.Parent, fullfile(folder_name,'figure.fig'));
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.InputparametersTab = uitab(app.TabGroup);
            app.InputparametersTab.Title = 'Input parameters';
            app.PARAMETERSLabel = uilabel(app.InputparametersTab);
            app.PARAMETERSLabel.Text = 'PARAMETERS ';
            app.SpacingmeasurespotgridEditFieldLabel = uilabel(app.InputparametersTab);
            app.SpacingmeasurespotgridEditFieldLabel.Tooltip = {'Distance between the points of the measure spot grid, in which we compute the power densities.'};
            app.SpacingmeasurespotgridEditFieldLabel.Text = 'Spacing measure spot grid';
            app.SpacingmeasurespotgridEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.SpacingmeasurespotgridEditField.Value = 5;
            app.LocalizationAccuracymEditFieldLabel = uilabel(app.InputparametersTab);
            app.LocalizationAccuracymEditFieldLabel.Tooltip = {'diameter (m) of area around  is deployed Uncertainty area, in which the user take place (the higher the worse the accuracy)'};
            app.LocalizationAccuracymEditFieldLabel.Text = 'Localization Accuracy [m]';
            app.LocalizationAccuracymEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.LocalizationAccuracymEditField.Tooltip = {''};
            app.LocalizationAccuracymEditField.Value = 2;
            app.UEheightmEditFieldLabel = uilabel(app.InputparametersTab);
            app.UEheightmEditFieldLabel.Text = 'UE height [m]';
            app.UEheightmEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.UEheightmEditField.Editable = 'off';
            app.UEheightmEditField.Value = 1.5;
            app.gNBheigthmEditFieldLabel = uilabel(app.InputparametersTab);
            app.gNBheigthmEditFieldLabel.Text = 'gNB heigth [m]';
            app.gNBheigthmEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.gNBheigthmEditField.Value = 15;
            app.CarrierfrequencyGHzEditFieldLabel = uilabel(app.InputparametersTab);
            app.CarrierfrequencyGHzEditFieldLabel.Text = 'Carrier frequency (GHz)';
            app.CarrierfrequencyGHzEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.CarrierfrequencyGHzEditField.Value = 3.5;
            app.PropagationconditionDropDownLabel = uilabel(app.InputparametersTab);
            app.PropagationconditionDropDownLabel.Text = 'Propagation condition';
            app.PropagationconditionDropDown = uidropdown(app.InputparametersTab);
            app.PropagationconditionDropDown.Items = {'LOS', 'NLOS'};
            app.PropagationconditionDropDown.ItemsData = [0 1];
            app.PropagationconditionDropDown.Value = 0;
            app.FronttoBackRatioAmdBEditFieldLabel = uilabel(app.InputparametersTab);
            app.FronttoBackRatioAmdBEditFieldLabel.Text = 'Front-to-Back Ratio (Am) [dB]';
            app.FronttoBackRatioAmdBEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.FronttoBackRatioAmdBEditField.Editable = 'off';
            app.FronttoBackRatioAmdBEditField.Value = 25;
            app.SidelobelevellimitSLAvdBEditFieldLabel = uilabel(app.InputparametersTab);
            app.SidelobelevellimitSLAvdBEditFieldLabel.Text = 'Side lobe level limit (SLAv) [dB]';
            app.SidelobelevellimitSLAvdBEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.SidelobelevellimitSLAvdBEditField.Editable = 'off';
            app.SidelobelevellimitSLAvdBEditField.Value = 20;
            app.ArraysizerowEditFieldLabel = uilabel(app.InputparametersTab);
            app.ArraysizerowEditFieldLabel.Tooltip = {'Number of radiant element in the horizontal dimention of the array antenna.'};
            app.ArraysizerowEditFieldLabel.Text = 'Array size row';
            app.ArraysizerowEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.ArraysizerowEditField.Editable = 'off';
            app.ArraysizerowEditField.Value = 8;
            app.ArraysizecolumnEditFieldLabel = uilabel(app.InputparametersTab);
            app.ArraysizecolumnEditFieldLabel.Tooltip = {'Number of radiant element in the vertical dimention of the array antenna.'};
            app.ArraysizecolumnEditFieldLabel.Text = 'Array size column';
            app.ArraysizecolumnEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.ArraysizecolumnEditField.Editable = 'off';
            app.ArraysizecolumnEditField.Value = 8;
            app.ArraytotaltransmitpowerdBmEditFieldLabel = uilabel(app.InputparametersTab);
            app.ArraytotaltransmitpowerdBmEditFieldLabel.Tooltip = {'Power allocated to an array.'};
            app.ArraytotaltransmitpowerdBmEditFieldLabel.Text = 'Array total transmit power [dBm]';
            app.ArraytotaltransmitpowerdBmEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.ArraytotaltransmitpowerdBmEditField.Value = 53;
            app.IntrasectorinterferenceSwitchLabel = uilabel(app.InputparametersTab);
            app.IntrasectorinterferenceSwitchLabel.Text = 'Intrasector interference';
            app.IntrasectorinterferenceSwitch = uiswitch(app.InputparametersTab, 'slider');
            app.IntrasectorinterferenceSwitch.ItemsData = [0 1];
            app.IntrasectorinterferenceSwitch.Value = 0;
            app.BandwidthMHzEditFieldLabel = uilabel(app.InputparametersTab);
            app.BandwidthMHzEditFieldLabel.Text = 'Bandwidth [MHz]';
            app.BandwidthMHzEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.BandwidthMHzEditField.Value = 80;
            app.ReceivernoisefiguredBEditFieldLabel = uilabel(app.InputparametersTab);
            app.ReceivernoisefiguredBEditFieldLabel.Text = 'Receiver noise figure [dB]';
            app.ReceivernoisefiguredBEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.ReceivernoisefiguredBEditField.Value = 5;
            app.Minimum3dBangledegEditFieldLabel = uilabel(app.InputparametersTab);
            app.Minimum3dBangledegEditFieldLabel.Text = 'Minimum 3dB angle [deg]';
            app.Minimum3dBangledegEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.Minimum3dBangledegEditField.Value = 3;
            app.MediumImpedenceOhmEditFieldLabel = uilabel(app.InputparametersTab);
            app.MediumImpedenceOhmEditFieldLabel.Text = 'Medium Impedence [Ohm]';
            app.MediumImpedenceOhmEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.MediumImpedenceOhmEditField.Editable = 'off';
            app.MediumImpedenceOhmEditField.Value = 377;
            app.TxGainantennaelementdBiEditField_2Label = uilabel(app.InputparametersTab);
            app.TxGainantennaelementdBiEditField_2Label.Text = 'Tx Gain antenna element [dBi]';
            app.TxGainantennaelementdBiEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.TxGainantennaelementdBiEditField.Value = 11.1;
            app.ScenarioDropDownLabel = uilabel(app.InputparametersTab);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.InputparametersTab);
            app.ScenarioDropDown.Items = {'UMa', 'UMi'};
            app.ScenarioDropDown.ItemsData = [0 1];
            app.ScenarioDropDown.Value = 0;
            app.DisableBeamformingLabel = uilabel(app.InputparametersTab);
            app.DisableBeamformingLabel.Text = 'Beamforming';
            app.BeamformingSwitch = uiswitch(app.InputparametersTab, 'slider');
            app.BeamformingSwitch.ItemsData = [0 1];
            app.BeamformingSwitch.ValueChangedFcn = createCallbackFcn(app, @BeamformingSwitchValueChanged, true);
            app.BeamformingSwitch.Value = 1;
            app.FixedanglesSwitchLabel = uilabel(app.InputparametersTab);
            app.FixedanglesSwitchLabel.Text = 'Fixed angles';
            app.FixedanglesSwitch = uiswitch(app.InputparametersTab, 'slider');
            app.FixedanglesSwitch.ItemsData = [0 1];
            app.FixedanglesSwitch.ValueChangedFcn = createCallbackFcn(app, @FixedanglesSwitchValueChanged, true);
            app.FixedanglesSwitch.Value = 0;
            app.AzimuthBeamwidthdegEditFieldLabel = uilabel(app.InputparametersTab);
            app.AzimuthBeamwidthdegEditFieldLabel.Enable = 'off';
            app.AzimuthBeamwidthdegEditFieldLabel.Text = 'Azimuth Beamwidth [deg]';
            app.AzimuthBeamwidthdegEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.AzimuthBeamwidthdegEditField.Enable = 'off';
            app.AzimuthBeamwidthdegEditField.Value = 90;
            app.ElevationBeamwidthdegEditFieldLabel = uilabel(app.InputparametersTab);
            app.ElevationBeamwidthdegEditFieldLabel.Enable = 'off';
            app.ElevationBeamwidthdegEditFieldLabel.Text = 'Elevation Beamwidth [deg]';
            app.ElevationBeamwidthdegEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.ElevationBeamwidthdegEditField.Enable = 'off';
            app.ElevationBeamwidthdegEditField.Value = 9;
            app.TestmodeTab = uitab(app.TabGroup);
            app.TestmodeTab.Title = 'Test mode';
            app.TestmodeTab.ButtonDownFcn = createCallbackFcn(app, @TestmodeTabButtonDown, true);
            app.GeographictopologyunavailablefortheSyntheticcaseLabel = uilabel(app.TestmodeTab);
            app.GeographictopologyunavailablefortheSyntheticcaseLabel.Text = {'Geographic topology unavailable'; ' for the Synthetic case'};
            app.ModeButtonGroup = uibuttongroup(app.TestmodeTab);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ModeButtonGroupButtonDown, true);
            app.SinglemodeButton = uiradiobutton(app.ModeButtonGroup);
            app.SinglemodeButton.Text = 'Single mode';
            app.SinglemodeButton.Value = true;
            app.MultimodeButton = uiradiobutton(app.ModeButtonGroup);
            app.MultimodeButton.Text = 'Multi mode ';
            app.MultimodeparametersPanel = uipanel(app.TestmodeTab);
            app.MinimumnumberofgNBsEditFieldLabel = uilabel(app.MultimodeparametersPanel);
            app.MinimumnumberofgNBsEditFieldLabel.Enable = 'off';
            app.MinimumnumberofgNBsEditFieldLabel.Text = 'Minimum number of gNBs';
            app.MinimumnumberofgNBsEditField = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.MinimumnumberofgNBsEditField.Enable = 'off';
            app.MinimumnumberofgNBsEditField.Value = 2;
            app.MaximumnumberofgNBsEditField_2Label = uilabel(app.MultimodeparametersPanel);
            app.MaximumnumberofgNBsEditField_2Label.Enable = 'off';
            app.MaximumnumberofgNBsEditField_2Label.Text = 'Maximum number of gNBs ';
            app.MaximumnumberofgNBsEditField = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.MaximumnumberofgNBsEditField.Enable = 'off';
            app.MaximumnumberofgNBsEditField.Value = 10;
            app.MinimumMOBJFcoefficientEditFieldLabel = uilabel(app.MultimodeparametersPanel);
            app.MinimumMOBJFcoefficientEditFieldLabel.Enable = 'off';
            app.MinimumMOBJFcoefficientEditFieldLabel.Tooltip = {'Starting value for the coefficient a for the cost term of the multiobjective function.'};
            app.MinimumMOBJFcoefficientEditFieldLabel.Text = 'Minimum MOBJF coefficient';
            app.MinimumMOBJFcoefficientEditField = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.MinimumMOBJFcoefficientEditField.Enable = 'off';
            app.MinimumMOBJFcoefficientEditField.Value = 0.1;
            app.MaximumMOBJFcoefficientEditFieldLabel = uilabel(app.MultimodeparametersPanel);
            app.MaximumMOBJFcoefficientEditFieldLabel.Enable = 'off';
            app.MaximumMOBJFcoefficientEditFieldLabel.Tooltip = {'Maximum value for the coefficient a for the cost term of the multiobjective function.'};
            app.MaximumMOBJFcoefficientEditFieldLabel.Text = 'Maximum MOBJF coefficient';
            app.MaximumMOBJFcoefficientEditField = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.MaximumMOBJFcoefficientEditField.Enable = 'off';
            app.MaximumMOBJFcoefficientEditField.Value = 0.9;
            app.StepEditFieldLabel = uilabel(app.MultimodeparametersPanel);
            app.StepEditFieldLabel.Enable = 'off';
            app.StepEditFieldLabel.Tooltip = {'How much is incremented the coefficient a for the network cost term, on the next set of coefficients of the multiobjective function.'};
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.StepEditField.Enable = 'off';
            app.StepEditField.Value = 0.1;
            app.GroupattemptsEditFieldLabel = uilabel(app.MultimodeparametersPanel);
            app.GroupattemptsEditFieldLabel.Enable = 'off';
            app.GroupattemptsEditFieldLabel.Tooltip = {'How many different subsets will be extracted for the current subset amplitude, before incrementing the number of gNB in the subset.'};
            app.GroupattemptsEditFieldLabel.Text = 'Group attempts';
            app.GroupattemptsEditField = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.GroupattemptsEditField.Enable = 'off';
            app.GroupattemptsEditField.Value = 20;
            app.MOBJweightsexplorationCheckBox = uicheckbox(app.MultimodeparametersPanel);
            app.MOBJweightsexplorationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MOBJweightsexplorationCheckBoxValueChanged, true);
            app.MOBJweightsexplorationCheckBox.Enable = 'off';
            app.MOBJweightsexplorationCheckBox.Tooltip = {'By checking it you can explore different sets of weights for the multiobjective function.'};
            app.MOBJweightsexplorationCheckBox.Text = 'MOBJ weights exploration';
            app.aEditField_2Label = uilabel(app.MultimodeparametersPanel);
            app.aEditField_2Label.Enable = 'off';
            app.aEditField_2Label.Tooltip = {'Coefficient for the network costs term (f1) of the multiobjective function.'};
            app.aEditField_2Label.Text = 'a';
            app.aEditField_2 = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.aEditField_2.Enable = 'off';
            app.aEditField_2.Value = 0.1;
            app.bEditField_2Label = uilabel(app.MultimodeparametersPanel);
            app.bEditField_2Label.Enable = 'off';
            app.bEditField_2Label.Tooltip = {'Coefficient for the network capacity term(f2) of the multiobjective function.'};
            app.bEditField_2Label.Text = 'b';
            app.bEditField_2 = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.bEditField_2.Enable = 'off';
            app.bEditField_2.Value = 0.45;
            app.cEditField_2Label = uilabel(app.MultimodeparametersPanel);
            app.cEditField_2Label.Enable = 'off';
            app.cEditField_2Label.Tooltip = {'Coefficient for the network emf strenght (f3) of the multiobjective function.'};
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.MultimodeparametersPanel, 'numeric');
            app.cEditField_2.Enable = 'off';
            app.cEditField_2.Value = 0.45;
            app.SinglemodeparametersPanel = uipanel(app.TestmodeTab);
            app.SinglemodeparametersPanel.ButtonDownFcn = createCallbackFcn(app, @SinglemodeparametersPanelButtonDown, true);
            app.NumberofgNBsEditFieldLabel = uilabel(app.SinglemodeparametersPanel);
            app.NumberofgNBsEditFieldLabel.Tooltip = {'Maximum number of gNB allowed to be installed. (in test phase is the same) '};
            app.NumberofgNBsEditFieldLabel.Text = ' Number of gNBs ';
            app.NumberofgNBsEditField = uieditfield(app.SinglemodeparametersPanel, 'numeric');
            app.NumberofgNBsEditField.Value = 4;
            app.aEditFieldLabel = uilabel(app.SinglemodeparametersPanel);
            app.aEditFieldLabel.Tooltip = {'Coefficient for the network costs term (f1) of the multiobjective function.'};
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.SinglemodeparametersPanel, 'numeric');
            app.aEditField.Value = 0.1;
            app.bEditFieldLabel = uilabel(app.SinglemodeparametersPanel);
            app.bEditFieldLabel.Tooltip = {'Coefficient for the network capacity term(f2) of the multiobjective function.'};
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.SinglemodeparametersPanel, 'numeric');
            app.bEditField.Value = 0.45;
            app.cEditFieldLabel = uilabel(app.SinglemodeparametersPanel);
            app.cEditFieldLabel.Tooltip = {'Coefficient for the network emf strenght (f3) of the multiobjective function.'};
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.SinglemodeparametersPanel, 'numeric');
            app.cEditField.Value = 0.45;
            app.KMLfileDropDownLabel = uilabel(app.TestmodeTab);
            app.KMLfileDropDownLabel.Enable = 'off';
            app.KMLfileDropDownLabel.Tooltip = {'To load a kml file, you should check the Real button above. Please note that you have to put the kml file into the folder "Scripts/kml".'};
            app.KMLfileDropDownLabel.Text = 'KML file';
            app.KMLfileDropDown = uidropdown(app.TestmodeTab);
            app.KMLfileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @KMLfileDropDownOpening, true);
            app.KMLfileDropDown.ValueChangedFcn = createCallbackFcn(app, @KMLfileDropDownValueChanged, true);
            app.KMLfileDropDown.Enable = 'off';
            app.KMLfileDropDown.ClickedFcn = createCallbackFcn(app, @KMLfileDropDownClicked, true);
            app.TopologyButtonGroup = uibuttongroup(app.TestmodeTab);
            app.TopologyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TopologyButtonGroupSelectionChanged, true);
            app.SyntheticButton = uiradiobutton(app.TopologyButtonGroup);
            app.SyntheticButton.Text = 'Synthetic';
            app.SyntheticButton.Value = true;
            app.RealButton = uiradiobutton(app.TopologyButtonGroup);
            app.RealButton.Text = 'Real';
            app.Lamp = uilamp(app.TestmodeTab);
            app.Lamp.Tooltip = {'To load a kml file, you should check the Real button above. Please note that you have to put the kml file into the folder "Scripts/kml".'};
            app.BasemapstyleButtonGroup = uibuttongroup(app.TestmodeTab);
            app.BasemapstyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BasemapstyleButtonGroupSelectionChanged, true);
            app.BasemapstyleButtonGroup.ButtonDownFcn = createCallbackFcn(app, @BasemapstyleButtonGroupButtonDown, true);
            app.streetsButton = uitogglebutton(app.BasemapstyleButtonGroup);
            app.streetsButton.Text = 'streets';
            app.streetsButton.Value = true;
            app.satelliteButton = uitogglebutton(app.BasemapstyleButtonGroup);
            app.satelliteButton.Text = 'satellite';
            app.streetsdarkButton = uitogglebutton(app.BasemapstyleButtonGroup);
            app.streetsdarkButton.Text = 'streets-dark';
            app.streetslightButton = uitogglebutton(app.BasemapstyleButtonGroup);
            app.streetslightButton.Text = 'streets-light';
            app.topographicButton = uitogglebutton(app.BasemapstyleButtonGroup);
            app.topographicButton.Text = 'topographic';
            app.paneltopologyplot = uipanel(app.TestmodeTab);
            app.paneltopologyplot.Visible = 'off';
            app.DisablecalibrationCheckBox = uicheckbox(app.TestmodeTab);
            app.DisablecalibrationCheckBox.Text = 'Disable calibration';
            app.PostprocessingCheckBox = uicheckbox(app.TestmodeTab);
            app.PostprocessingCheckBox.Text = 'Post processing';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.UIAxes = uiaxes(app.PlottingTab);
            title(app.UIAxes, 'Heatmap of the beams')
            xlabel(app.UIAxes, 'X[m]')
            ylabel(app.UIAxes, 'Y[m]')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'turbo')
            app.emfecdf = uiaxes(app.PlottingTab);
            title(app.emfecdf, 'Downlink CDF of EMF strength over the measure spots')
            xlabel(app.emfecdf, 'E[V/m]')
            ylabel(app.emfecdf, 'Prob[E < abscisse]')
            zlabel(app.emfecdf, 'Z')
            app.throughputecdf = uiaxes(app.PlottingTab);
            title(app.throughputecdf, 'Downlink Throughput CDF of the served users')
            xlabel(app.throughputecdf, 'Throughput[Mbps]')
            ylabel(app.throughputecdf, 'Prob[Throughput < abscisse]')
            zlabel(app.throughputecdf, 'Z')
            app.ECDFEMFstrengthButton = uibutton(app.PlottingTab, 'push');
            app.ECDFEMFstrengthButton.ButtonPushedFcn = createCallbackFcn(app, @ECDFEMFstrengthButtonPushed, true);
            app.ECDFEMFstrengthButton.Text = 'ECDF EMF strength';
            app.ECDFThroughputButton = uibutton(app.PlottingTab, 'push');
            app.ECDFThroughputButton.ButtonPushedFcn = createCallbackFcn(app, @ECDFThroughputButtonPushed, true);
            app.ECDFThroughputButton.Text = 'ECDF Throughput  ';
            app.MeasurespotgridButton = uibutton(app.PlottingTab, 'push');
            app.MeasurespotgridButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurespotgridButtonPushed, true);
            app.MeasurespotgridButton.Enable = 'off';
            app.MeasurespotgridButton.Text = 'Measure spot grid';
            app.ServedandUnservedspotsButton = uibutton(app.PlottingTab, 'push');
            app.ServedandUnservedspotsButton.ButtonPushedFcn = createCallbackFcn(app, @ServedandUnservedspotsButtonPushed, true);
            app.ServedandUnservedspotsButton.Text = 'Served and Unserved spots';
            app.BeamsHeatmapButton = uibutton(app.PlottingTab, 'push');
            app.BeamsHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @BeamsHeatmapButtonPushed, true);
            app.BeamsHeatmapButton.Text = 'Beams Heatmap';
            app.ShowoutsideGUICheckBox = uicheckbox(app.PlottingTab);
            app.ShowoutsideGUICheckBox.Text = 'Show outside GUI';
            app.CLEARButton = uibutton(app.PlottingTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.SensitivityanalysisButton = uibutton(app.PlottingTab, 'push');
            app.SensitivityanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SensitivityanalysisButtonPushed, true);
            app.SensitivityanalysisButton.Enable = 'off';
            app.SensitivityanalysisButton.Text = 'Sensitivity analysis';
            app.CurrentbestMOBJtrendButton = uibutton(app.PlottingTab, 'push');
            app.CurrentbestMOBJtrendButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentbestMOBJtrendButtonPushed, true);
            app.CurrentbestMOBJtrendButton.Enable = 'off';
            app.CurrentbestMOBJtrendButton.Text = 'Current best MOBJ trend';
            app.winnerplotButton = uibutton(app.PlottingTab, 'push');
            app.winnerplotButton.ButtonPushedFcn = createCallbackFcn(app, @winnerplotButtonPushed, true);
            app.winnerplotButton.Enable = 'off';
            app.winnerplotButton.Text = 'winnerplot';
            app.CLEARButton_2 = uibutton(app.PlottingTab, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.CLEARButton_3 = uibutton(app.PlottingTab, 'push');
            app.CLEARButton_3.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_3Pushed, true);
            app.CLEARButton_3.Text = 'CLEAR';
            app.IMPORTButton = uibutton(app.PlottingTab, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            app.IDEditFieldLabel = uilabel(app.PlottingTab);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.PlottingTab, 'numeric');
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.SizeChangedFcn = createCallbackFcn(app, @DashboardTabSizeChanged, true);
            app.DashboardTab.Title = 'Dashboard';
            app.FORCESTOPButton = uibutton(app.DashboardTab, 'push');
            app.FORCESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @FORCESTOPButtonPushed, true);
            app.FORCESTOPButton.Text = 'FORCE STOP';
            app.OKButton = uibutton(app.DashboardTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel_2 = uipanel(app.DashboardTab);
            app.TextArea_2Label_4 = uilabel(app.Panel_2);
            app.TextArea_2Label_4.Text = '';
            app.livenumberattempts = uitextarea(app.Panel_2);
            app.livenumberattempts.Editable = 'off';
            app.AttemptsGaugeLabel = uilabel(app.Panel_2);
            app.AttemptsGaugeLabel.Text = 'Attempts';
            app.AttemptsGauge = uigauge(app.Panel_2, 'semicircular');
            app.AttemptsGauge.Limits = [0 50];
            app.Panel_3 = uipanel(app.DashboardTab);
            app.TextArea_2Label_5 = uilabel(app.Panel_3);
            app.TextArea_2Label_5.Text = '';
            app.livenumbersubsetamplitude = uitextarea(app.Panel_3);
            app.livenumbersubsetamplitude.Editable = 'off';
            app.SubsetamplitudeLabel = uilabel(app.Panel_3);
            app.SubsetamplitudeLabel.Text = 'Subset amplitude';
            app.SubsetAmplitudeGauge = uigauge(app.Panel_3, 'semicircular');
            app.SubsetAmplitudeGauge.Limits = [0 50];
            app.Panel_4 = uipanel(app.DashboardTab);
            app.liveplot = uiaxes(app.Panel_4);
            title(app.liveplot, 'Multiobjective solution space for different generations ID')
            xlabel(app.liveplot, 'Objective 1 : Fitness function Costs f1')
            ylabel(app.liveplot, 'Objective 2 : Fitness function Throughput f2')
            zlabel(app.liveplot, 'Objective 3 : Fitness function EMF strength f3')
            app.liveplot.ButtonDownFcn = createCallbackFcn(app, @liveplotButtonDown, true);
            app.Panel_5 = uipanel(app.DashboardTab);
            app.liveplot_2 = uiaxes(app.Panel_5);
            title(app.liveplot_2, 'Global objective function value')
            xlabel(app.liveplot_2, 'ID')
            ylabel(app.liveplot_2, 'MOBJ')
            zlabel(app.liveplot_2, 'Z')
            app.Panel_6 = uipanel(app.DashboardTab);
            app.CurrentIDLabel = uilabel(app.Panel_6);
            app.CurrentIDLabel.Text = 'Current ID';
            app.TextArea_2Label_3 = uilabel(app.Panel_6);
            app.TextArea_2Label_3.Text = '';
            app.liveid = uitextarea(app.Panel_6);
            app.liveid.ValueChangedFcn = createCallbackFcn(app, @liveidValueChanged, true);
            app.liveid.Editable = 'off';
            app.Panel_7 = uipanel(app.DashboardTab);
            app.CalibrationstatusLamp = uilamp(app.Panel_7);
            app.CalibrationstatusLabel = uilabel(app.Panel_7);
            app.CalibrationstatusLabel.Text = {'Calibration'; 'status'};
            app.Panel_8 = uipanel(app.DashboardTab);
            app.calibrationtimerLabel = uilabel(app.Panel_8);
            app.calibrationtimerLabel.Text = {'Calibration '; 'time'};
            app.TextArea_2Label_6 = uilabel(app.Panel_8);
            app.TextArea_2Label_6.Text = '';
            app.calibrationtime = uitextarea(app.Panel_8);
            app.calibrationtime.Editable = 'off';
            app.Panel_9 = uipanel(app.DashboardTab);
            app.ExecutiontotaltimeLabel = uilabel(app.Panel_9);
            app.ExecutiontotaltimeLabel.Text = {'Total '; 'time'};
            app.TextArea_2Label_7 = uilabel(app.Panel_9);
            app.TextArea_2Label_7.Text = '';
            app.Executiontotaltime = uitextarea(app.Panel_9);
            app.Executiontotaltime.Editable = 'off';
            app.Panel_10 = uipanel(app.DashboardTab);
            app.testmodeviewerLabel = uilabel(app.Panel_10);
            app.testmodeviewerLabel.Text = 'Current test mode';
            app.TextArea_2Label_8 = uilabel(app.Panel_10);
            app.TextArea_2Label_8.Text = '';
            app.testmodeviewer = uitextarea(app.Panel_10);
            app.testmodeviewer.Editable = 'off';
            app.Panel_11 = uipanel(app.DashboardTab);
            app.liveplot_3 = uiaxes(app.Panel_11);
            title(app.liveplot_3, 'Electric field strength network')
            xlabel(app.liveplot_3, 'ID ')
            ylabel(app.liveplot_3, 'V/m TOT')
            zlabel(app.liveplot_3, 'Z')
            app.Panel_12 = uipanel(app.DashboardTab);
            app.liveplot_4 = uiaxes(app.Panel_12);
            title(app.liveplot_4, 'Throughput network')
            xlabel(app.liveplot_4, 'ID ')
            ylabel(app.liveplot_4, 'bps TOT')
            zlabel(app.liveplot_4, 'Z')
            app.Panel_13 = uipanel(app.DashboardTab);
            app.liveplot_5 = uiaxes(app.Panel_13);
            title(app.liveplot_5, 'Installation costs')
            xlabel(app.liveplot_5, 'ID ')
            ylabel(app.liveplot_5, '€')
            zlabel(app.liveplot_5, 'Z')
            app.Panel_14 = uipanel(app.DashboardTab);
            app.WinnerIDLabel = uilabel(app.Panel_14);
            app.WinnerIDLabel.Text = 'ID WINNER';
            app.TextArea_2Label_9 = uilabel(app.Panel_14);
            app.TextArea_2Label_9.Text = '';
            app.WinnerID = uitextarea(app.Panel_14);
            app.WinnerID.Editable = 'off';
            app.ReplotButton = uibutton(app.DashboardTab, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.ParetofrontsolutionsButton = uibutton(app.DashboardTab, 'push');
            app.ParetofrontsolutionsButton.ButtonPushedFcn = createCallbackFcn(app, @ParetofrontsolutionsButtonPushed, true);
            app.ParetofrontsolutionsButton.Text = 'Pareto front solutions';
            app.SubsetAmplitudeDropDownLabel = uilabel(app.DashboardTab);
            app.SubsetAmplitudeDropDownLabel.Text = 'Subset Amplitude';
            app.SubsetAmplitudeDropDown = uidropdown(app.DashboardTab);
            app.SubsetAmplitudeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SubsetAmplitudeDropDownOpening, true);
            app.SubsetAmplitudeDropDown.ClickedFcn = createCallbackFcn(app, @SubsetAmplitudeDropDownClicked, true);
            app.UITable = uitable(app.DashboardTab);
            app.UITable.ColumnName = {'ID'; 'Attempt'; 'f1'; 'f2'; 'f3'};
            app.UITable.RowName = {};
            app.TabCOVER = uipanel(app.UIFigure);
            app.DashboardLINK = uibutton(app.TabCOVER, 'push');
            app.DashboardLINK.ButtonPushedFcn = createCallbackFcn(app, @DashboardLINKPushed, true);
            app.DashboardLINK.Icon = fullfile(pathToMLAPP, 'Icons', 'dashboard.png');
            app.DashboardLINK.Text = '';
            app.InputparametersLINK = uibutton(app.TabCOVER, 'push');
            app.InputparametersLINK.ButtonPushedFcn = createCallbackFcn(app, @InputparametersLINKButtonPushed, true);
            app.InputparametersLINK.Icon = fullfile(pathToMLAPP, 'Icons', 'setting.png');
            app.InputparametersLINK.Tooltip = {'Simulation parameters settings'};
            app.InputparametersLINK.Text = '';
            app.infoLINK = uibutton(app.TabCOVER, 'push');
            app.infoLINK.ButtonPushedFcn = createCallbackFcn(app, @infoLINKButtonPushed, true);
            app.infoLINK.Icon = fullfile(pathToMLAPP, 'Icons', 'link.png');
            app.infoLINK.Tooltip = {'Me on Linkedin'};
            app.infoLINK.Text = '';
            app.PlottingLINK = uibutton(app.TabCOVER, 'push');
            app.PlottingLINK.ButtonPushedFcn = createCallbackFcn(app, @PlottingLINKButtonPushed, true);
            app.PlottingLINK.Icon = fullfile(pathToMLAPP, 'Icons', 'plots.png');
            app.PlottingLINK.Tooltip = {'Plots'};
            app.PlottingLINK.Text = '';
            app.TestmodeLINK = uibutton(app.TabCOVER, 'push');
            app.TestmodeLINK.ButtonPushedFcn = createCallbackFcn(app, @TestmodeLINKButtonPushed, true);
            app.TestmodeLINK.Icon = fullfile(pathToMLAPP, 'Icons', 'layer.png');
            app.TestmodeLINK.Tooltip = {'Topology and test settings'};
            app.TestmodeLINK.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end